classdef ENATest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DataPanel                    matlab.ui.container.Panel
        ExploreButton                matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        DataLogButton                matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        SavePathEditField            matlab.ui.control.EditField
        SavePathEditFieldLabel       matlab.ui.control.Label
        MeasurementPanel             matlab.ui.container.Panel
        PauseButton                  matlab.ui.control.Button
        SetInitialTrace2Button       matlab.ui.control.Button
        SetInitialTrace1Button       matlab.ui.control.Button
        GetInitialTrace2Button       matlab.ui.control.Button
        GetInitialTrace1Button       matlab.ui.control.Button
        ENASetupPanel                matlab.ui.container.Panel
        ENAAddressEditFieldLabel     matlab.ui.control.Label
        ENAAddressEditField          matlab.ui.control.EditField
        ConnectButton                matlab.ui.control.Button
        DisconnectButton             matlab.ui.control.Button
        FindPeaksParameterPanel      matlab.ui.container.Panel
        SetButton                    matlab.ui.control.Button
        MinPeakHeightEditField       matlab.ui.control.NumericEditField
        MinPeakHeightEditFieldLabel  matlab.ui.control.Label
        MinPeakwidthEditField        matlab.ui.control.NumericEditField
        MinPeakwidthEditFieldLabel   matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        MinPeakProminanceEditField   matlab.ui.control.NumericEditField
        MinPeakProminanceEditFieldLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        RightPanel                   matlab.ui.container.Panel
        diffTrace                    matlab.ui.control.UIAxes
        temporalTrace                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ENA % visaENA class
        running = false % system running
        pauseFlag = false %pause flag
        dataLogFlag = false %log data flag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetInitialTrace1Button
        function GetInitialTrace1ButtonPushed(app, event)
           app.ENA.getInitialValue1();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ENA = visaENA(app.ENAAddressEditField.Value);
            app.running = true;
            app.GetInitialTrace1Button.Enable = "on";
            app.GetInitialTrace2Button.Enable = "on";
            app.SetInitialTrace1Button.Enable = "on";
            app.SetInitialTrace2Button.Enable = "on";
            app.DataLogButton.Enable="on";
            app.ResetButton.Enable = "on";
            app.SaveButton.Enable = "on";
            app.PauseButton.Enable = "on";
            app.SetButton.Enable = "on";

            app.ENA.showTrace(app.temporalTrace);
            app.ENA.showDifference(app.diffTrace);

            while app.running
                if app.pauseFlag
                    pause(0.1);
                    continue;
                end
                try
                    app.ENA.updateTrace();
                catch 
                    warning('Get Trace Error');
                end
                if app.dataLogFlag
                    app.ENA.LogData();
                end
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.ENA.delete();
            app.running = false;
            app.GetInitialTrace1Button.Enable = "off";
            app.GetInitialTrace2Button.Enable = "off";
            app.SetInitialTrace1Button.Enable = "off";
            app.SetInitialTrace2Button.Enable = "off";
            app.DataLogButton.Enable="off";
            app.ResetButton.Enable = "off";
            app.SaveButton.Enable = "off";
            app.PauseButton.Enable = "off";
            app.SetButton.Enable = "off";
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ENA.MinPeakProminance = app.MinPeakProminanceEditField.Value;
            app.ENA.Threshold = app.ThresholdEditField.Value;
            app.ENA.MinPeakHeight = app.MinPeakHeightEditField.Value;
            app.ENA.MinPeakWidth = app.MinPeakwidthEditField.Value;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pauseFlag = ~app.pauseFlag;
            switch app.pauseFlag
                case false
                    app.PauseButton.Text = 'Pause';
                case true
                    app.PauseButton.Text = 'Resume';
            end
        end

        % Button pushed function: SetInitialTrace2Button
        function SetInitialTrace2ButtonPushed(app, event)
            app.ENA.setInitialValue2();
        end

        % Button pushed function: SetInitialTrace1Button
        function SetInitialTrace1ButtonPushed(app, event)
            app.ENA.setInitialValue1();
        end

        % Button pushed function: GetInitialTrace2Button
        function GetInitialTrace2ButtonPushed(app, event)
            app.ENA.getInitialValue2();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.ENA.savePath=app.SavePathEditField.Value;
            app.ENA.SaveData();
        end

        % Button pushed function: DataLogButton
        function DataLogButtonPushed(app, event)
            app.dataLogFlag = ~app.dataLogFlag;
            switch app.dataLogFlag
                case false
                    app.DataLogButton.Text = 'Start Data Log';
                case true
                    app.DataLogButton.Text = 'Pause Data Log';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ENA.initDataToSave();
            app.dataLogFlag=false;
            app.DataLogButton.Text='Start Data Log';
        end

        % Button pushed function: ExploreButton
        function ExploreButtonPushed(app, event)
            selectedPath = uigetdir();
            if selectedPath ~=0
                app.SavePathEditField.Value = selectedPath;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {852, 852};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LOGO2.png');
            app.FindPeaksParameterPanel = uipanel(app.LeftPanel);
            app.FindPeaksParameterPanel.Title = 'Find Peaks Parameter';
            app.MinPeakProminanceEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.MinPeakProminanceEditFieldLabel.Text = 'Min Peak Prominance';
            app.MinPeakProminanceEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.MinPeakProminanceEditField.Value = 0.2;
            app.ThresholdEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.MinPeakwidthEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.MinPeakwidthEditFieldLabel.Text = 'Min Peak width';
            app.MinPeakwidthEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.MinPeakHeightEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.MinPeakHeightEditFieldLabel.Text = 'Min Peak Height';
            app.MinPeakHeightEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.SetButton = uibutton(app.FindPeaksParameterPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.ENASetupPanel = uipanel(app.LeftPanel);
            app.ENASetupPanel.Title = 'ENA Setup';
            app.DisconnectButton = uibutton(app.ENASetupPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.ENASetupPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ENAAddressEditField = uieditfield(app.ENASetupPanel, 'text');
            app.ENAAddressEditField.Value = 'USB0::0x2A8D::0x5A01::MY46733702::0::INSTR';
            app.ENAAddressEditFieldLabel = uilabel(app.ENASetupPanel);
            app.ENAAddressEditFieldLabel.Text = 'ENA Address';
            app.MeasurementPanel = uipanel(app.LeftPanel);
            app.MeasurementPanel.Title = 'Measurement';
            app.GetInitialTrace1Button = uibutton(app.MeasurementPanel, 'push');
            app.GetInitialTrace1Button.ButtonPushedFcn = createCallbackFcn(app, @GetInitialTrace1ButtonPushed, true);
            app.GetInitialTrace1Button.Enable = 'off';
            app.GetInitialTrace1Button.Text = 'Get Initial Trace1';
            app.GetInitialTrace2Button = uibutton(app.MeasurementPanel, 'push');
            app.GetInitialTrace2Button.ButtonPushedFcn = createCallbackFcn(app, @GetInitialTrace2ButtonPushed, true);
            app.GetInitialTrace2Button.Enable = 'off';
            app.GetInitialTrace2Button.Text = 'Get Initial Trace2';
            app.SetInitialTrace1Button = uibutton(app.MeasurementPanel, 'push');
            app.SetInitialTrace1Button.ButtonPushedFcn = createCallbackFcn(app, @SetInitialTrace1ButtonPushed, true);
            app.SetInitialTrace1Button.Enable = 'off';
            app.SetInitialTrace1Button.Text = 'Set Initial Trace1';
            app.SetInitialTrace2Button = uibutton(app.MeasurementPanel, 'push');
            app.SetInitialTrace2Button.ButtonPushedFcn = createCallbackFcn(app, @SetInitialTrace2ButtonPushed, true);
            app.SetInitialTrace2Button.Enable = 'off';
            app.SetInitialTrace2Button.Text = 'Set Initial Trace2';
            app.PauseButton = uibutton(app.MeasurementPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.DataPanel = uipanel(app.LeftPanel);
            app.DataPanel.Title = 'Data';
            app.SavePathEditFieldLabel = uilabel(app.DataPanel);
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.DataPanel, 'text');
            app.SavePathEditField.Value = '.\Data\';
            app.SaveButton = uibutton(app.DataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.DataLogButton = uibutton(app.DataPanel, 'push');
            app.DataLogButton.ButtonPushedFcn = createCallbackFcn(app, @DataLogButtonPushed, true);
            app.DataLogButton.Enable = 'off';
            app.DataLogButton.Text = 'Start Data Log';
            app.ResetButton = uibutton(app.DataPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ExploreButton = uibutton(app.DataPanel, 'push');
            app.ExploreButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreButtonPushed, true);
            app.ExploreButton.Text = 'Explore';
            app.RightPanel = uipanel(app.GridLayout);
            app.temporalTrace = uiaxes(app.RightPanel);
            title(app.temporalTrace, 'Temporal Trace')
            xlabel(app.temporalTrace, 'X')
            ylabel(app.temporalTrace, 'Y')
            zlabel(app.temporalTrace, 'Z')
            app.temporalTrace.YLim = [-7 2];
            app.diffTrace = uiaxes(app.RightPanel);
            title(app.diffTrace, 'Differential Trace')
            xlabel(app.diffTrace, 'X')
            ylabel(app.diffTrace, 'Y')
            zlabel(app.diffTrace, 'Z')
            app.diffTrace.YLim = [-7 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENATest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end